classdef AllWidgetsTestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout4               matlab.ui.container.GridLayout
        Toolbar                   wt.Toolbar
        ToolbarLabel              matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        ProgressBarLabel          matlab.ui.control.Label
        PasswordFieldLabel        matlab.ui.control.Label
        ProgressBar               wt.ProgressBar
        PasswordField             wt.PasswordField
        SliderSpinner             wt.SliderSpinner
        SliderSpinnerComboLabel   matlab.ui.control.Label
        SliderCheckboxLabel       matlab.ui.control.Label
        SliderCheckboxGroup       wt.SliderCheckboxGroup
        TaskStatusListLabel       matlab.ui.control.Label
        TaskStatusTable           wt.TaskStatusTable
        GridLayout                matlab.ui.container.GridLayout
        ListSelectorTwoPane       wt.ListSelectorTwoPane
        ListSelector              wt.ListSelector
        ListSelectortwopaneLabel  matlab.ui.control.Label
        CheckboxList              wt.CheckboxList
        DatetimeSelectorLabel     matlab.ui.control.Label
        ColorSelectorLabel        matlab.ui.control.Label
        CheckboxListLabel         matlab.ui.control.Label
        ListSelectorLabel         matlab.ui.control.Label
        FileSelectorLabel         matlab.ui.control.Label
        ButtonGridLabel           matlab.ui.control.Label
        FileSelector              wt.FileSelector
        DatetimeSelector          wt.DatetimeSelector
        ColorSelector             wt.ColorSelector
        ButtonGrid                wt.ButtonGrid
    end


    properties (Access = public)
        ToolbarSection1
        ToolbarSection2
        ToolbarStateButton matlab.ui.control.StateButton
        ToolbarSection3h
        ToolbarSection3v
        ToolbarSlider
        ToolbarSection4
    end

    methods (Access = private)

        function createToolbarContent(app)

            % Create a horizontal section
            app.ToolbarSection1 = wt.toolbar.HorizontalSection();
            app.ToolbarSection1.Title = "NORMAL BUTTONS";
            app.ToolbarSection1.addButton("open_24.png", "Open");
            app.ToolbarSection1.addButton("save_24.png", "Save");

            % Create a horizontal section with state buttons
            app.ToolbarSection2 = wt.toolbar.HorizontalSection();
            app.ToolbarSection2.Title = "STATE BUTTONS";
            app.ToolbarStateButton(1) = app.ToolbarSection2.addStateButton("","Mode 1");
            app.ToolbarStateButton(2) = app.ToolbarSection2.addStateButton("","Mode 2");
            app.ToolbarStateButton(3) = app.ToolbarSection2.addStateButton("","Mode 3");

            % Set the state of the buttons
            app.ToolbarStateButton(1).Value = true;
            app.ToolbarStateButton(2).Value = false;
            app.ToolbarStateButton(3).Value = false;

            % Create a horizontal section with vertical subsection
            app.ToolbarSection3h = wt.toolbar.HorizontalSection();
            app.ToolbarSection3h.Title = "MIXED HORIZONTAL / VERTICAL";
            app.ToolbarSection3h.addButton("left_24.png","");
            app.ToolbarSection3h.addButton("right_24.png","");

            % Create the vertical subsection
            app.ToolbarSection3v = app.ToolbarSection3h.addVerticalSection();
            app.ToolbarSection3v.addButton("play_24.png","Play");
            app.ToolbarSection3v.addButton("stop_24.png","Stop");

            % Add a manual component to the  vertical section
            app.ToolbarSlider = uislider("Parent",[]);
            app.ToolbarSection3v.Component(end+1) = app.ToolbarSlider;

            % Attach a callback to the slider
            % This is required for manually adding components
            app.ToolbarSlider.ValueChangedFcn = @(src,evt)disp(evt);

            % Create more horizontal items
            app.ToolbarSection3h.addButton("pause_24.png","Pause");

            % Add another basic section
            app.ToolbarSection4 = wt.toolbar.HorizontalSection();
            app.ToolbarSection4.Title = "HELP";
            app.ToolbarSection4.addButton('help_24.png','Help');

            % Attach the horizontal sections to the toolbar
            app.Toolbar.Section = [
                app.ToolbarSection1
                app.ToolbarSection2
                app.ToolbarSection3h
                app.ToolbarSection4
                ];

        end

    end %methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Send "app" to base workspace for debugging
            assignin("base","app",app)

            % Populate the toolbar
            app.createToolbarContent();
            % Start progress
            app.ProgressBar.startProgress();
            app.ProgressBar.setProgress(0.4, "Running...");

        end

        % Callback function: PasswordField
        function PasswordFieldValueChanged(app, event)

            value = app.PasswordField.Value;
            uialert(app.UIFigure, value, "Password Entered")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Widgets Test App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.GridLayout = uigridlayout(app.GridLayout2);
            app.ButtonGrid = wt.ButtonGrid(app.GridLayout);
            app.ButtonGrid.Text = "Up";
            app.ButtonGrid.ButtonWidth = {'1x', '1x'};
            app.ColorSelector = wt.ColorSelector(app.GridLayout);
            app.DatetimeSelector = wt.DatetimeSelector(app.GridLayout);
            app.FileSelector = wt.FileSelector(app.GridLayout);
            app.FileSelector.Value = "C:\Work";
            app.FileSelector.ShowHistory = 'on';
            app.FileSelector.History = "C:\Work";
            app.ButtonGridLabel = uilabel(app.GridLayout);
            app.ButtonGridLabel.Text = 'Button Grid';
            app.FileSelectorLabel = uilabel(app.GridLayout);
            app.FileSelectorLabel.Text = 'File Selector';
            app.ListSelectorLabel = uilabel(app.GridLayout);
            app.ListSelectorLabel.Text = 'List Selector';
            app.CheckboxListLabel = uilabel(app.GridLayout);
            app.CheckboxListLabel.Text = 'Checkbox List';
            app.ColorSelectorLabel = uilabel(app.GridLayout);
            app.ColorSelectorLabel.Text = 'Color Selector';
            app.DatetimeSelectorLabel = uilabel(app.GridLayout);
            app.DatetimeSelectorLabel.Text = 'Datetime Selector';
            app.CheckboxList = wt.CheckboxList(app.GridLayout);
            app.ListSelectortwopaneLabel = uilabel(app.GridLayout);
            app.ListSelectortwopaneLabel.Text = 'List Selector (two pane)';
            app.ListSelector = wt.ListSelector(app.GridLayout);
            app.ListSelectorTwoPane = wt.ListSelectorTwoPane(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.TaskStatusTable = wt.TaskStatusTable(app.GridLayout3);
            app.TaskStatusListLabel = uilabel(app.GridLayout3);
            app.TaskStatusListLabel.Text = 'Task Status List';
            app.SliderCheckboxGroup = wt.SliderCheckboxGroup(app.GridLayout3);
            app.SliderCheckboxGroup.Value = [0.3 0.5 0.7];
            app.SliderCheckboxLabel = uilabel(app.GridLayout3);
            app.SliderCheckboxLabel.Text = 'Slider Checkbox';
            app.SliderSpinnerComboLabel = uilabel(app.GridLayout3);
            app.SliderSpinnerComboLabel.Text = 'Slider Spinner Combo';
            app.SliderSpinner = wt.SliderSpinner(app.GridLayout3);
            app.SliderSpinner.MinorTickLabels = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            app.PasswordField = wt.PasswordField(app.GridLayout3);
            app.PasswordField.Value = "abc";
            app.PasswordField.ValueChangedFcn = createCallbackFcn(app, @PasswordFieldValueChanged, true);
            app.ProgressBar = wt.ProgressBar(app.GridLayout3);
            app.ProgressBar.ShowCancel = 'on';
            app.PasswordFieldLabel = uilabel(app.GridLayout3);
            app.PasswordFieldLabel.Text = 'Password Field';
            app.ProgressBarLabel = uilabel(app.GridLayout3);
            app.ProgressBarLabel.Text = 'Progress Bar';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ToolbarLabel = uilabel(app.GridLayout4);
            app.ToolbarLabel.Text = 'Toolbar';
            app.Toolbar = wt.Toolbar(app.GridLayout4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AllWidgetsTestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end