classdef PasswordFieldTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PasswordTestAppUIFigure         matlab.ui.Figure
        RefreshButton                   matlab.ui.control.Button
        PasswordFieldValueEditField     matlab.ui.control.EditField
        PasswordFieldValueEditFieldLabel  matlab.ui.control.Label
        ChangedeventdataEditField       matlab.ui.control.EditField
        ChangedeventdataEditFieldLabel  matlab.ui.control.Label
        ChangingeventdataEditField      matlab.ui.control.EditField
        ChangingeventdataEditFieldLabel  matlab.ui.control.Label
        PasswordFieldTestLabel          matlab.ui.control.Label
        PasswordLabel                   matlab.ui.control.Label
        PasswordField                   wt.PasswordField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PasswordField
        function PasswordFieldValueChanged(app, event)
            changedValue = event.Value;
            app.ChangedeventdataEditField.Value = changedValue;

            fieldValue = app.PasswordField.Value;
            app.PasswordFieldValueEditField.Value = fieldValue;
        end

        % Callback function: PasswordField
        function PasswordFieldValueChanging(app, event)
            changingValue = event.Value;
            app.ChangingeventdataEditField.Value = changingValue;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            fieldValue = app.PasswordField.Value;
            app.PasswordFieldValueEditField.Value = fieldValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PasswordTestAppUIFigure = uifigure('Visible', 'off');
            app.PasswordTestAppUIFigure.Name = 'Password Test App';
            app.PasswordField = wt.PasswordField(app.PasswordTestAppUIFigure);
            app.PasswordField.ValueChangedFcn = createCallbackFcn(app, @PasswordFieldValueChanged, true);
            app.PasswordField.ValueChangingFcn = createCallbackFcn(app, @PasswordFieldValueChanging, true);
            app.PasswordLabel = uilabel(app.PasswordTestAppUIFigure);
            app.PasswordLabel.Text = 'Password:';
            app.PasswordFieldTestLabel = uilabel(app.PasswordTestAppUIFigure);
            app.PasswordFieldTestLabel.Text = 'Password Field Test';
            app.ChangingeventdataEditFieldLabel = uilabel(app.PasswordTestAppUIFigure);
            app.ChangingeventdataEditFieldLabel.Text = 'Changing event data:';
            app.ChangingeventdataEditField = uieditfield(app.PasswordTestAppUIFigure, 'text');
            app.ChangingeventdataEditField.Editable = 'off';
            app.ChangedeventdataEditFieldLabel = uilabel(app.PasswordTestAppUIFigure);
            app.ChangedeventdataEditFieldLabel.Text = 'Changed event data:';
            app.ChangedeventdataEditField = uieditfield(app.PasswordTestAppUIFigure, 'text');
            app.ChangedeventdataEditField.Editable = 'off';
            app.PasswordFieldValueEditFieldLabel = uilabel(app.PasswordTestAppUIFigure);
            app.PasswordFieldValueEditFieldLabel.Text = 'Password Field Value:';
            app.PasswordFieldValueEditField = uieditfield(app.PasswordTestAppUIFigure, 'text');
            app.PasswordFieldValueEditField.Editable = 'off';
            app.RefreshButton = uibutton(app.PasswordTestAppUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.PasswordTestAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PasswordFieldTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PasswordTestAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PasswordTestAppUIFigure)
        end
    end
end