classdef WidgetsExampleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WidgetsToolboxExamplesAppUIFigure  matlab.ui.Figure
        MainGrid                  matlab.ui.container.GridLayout
        RightGrid                 matlab.ui.container.GridLayout
        TaskStatusTable           wt.TaskStatusTable
        SliderCheckboxLabel_2     matlab.ui.control.Label
        TaskStatusTableLabel      matlab.ui.control.Label
        SliderSpinnerComboLabel   matlab.ui.control.Label
        RangeFieldLabel           matlab.ui.control.Label
        ProgressBarLabel          matlab.ui.control.Label
        PasswordFieldLabel        matlab.ui.control.Label
        NumericArrayLabel         matlab.ui.control.Label
        SliderSpinner             wt.SliderSpinner
        SliderCheckboxGroup       wt.SliderCheckboxGroup
        RangeField                wt.RangeField
        ProgressBar               wt.ProgressBar
        PasswordField             wt.PasswordField
        NumericArray              wt.NumericArray
        LeftGrid                  matlab.ui.container.GridLayout
        CheckboxList              wt.CheckboxList
        ListSelectorTwoPane       wt.ListSelectorTwoPane
        ListSelectorTwoPaneLabel  matlab.ui.control.Label
        ListSelectorLabel         matlab.ui.control.Label
        FileSelectorLabel         matlab.ui.control.Label
        DropDownListManagerLabel  matlab.ui.control.Label
        DatetimeSelectorLabel     matlab.ui.control.Label
        ColorSelectorLabel        matlab.ui.control.Label
        CheckboxListLabel         matlab.ui.control.Label
        ButtonGridLabel           matlab.ui.control.Label
        ListSelector              wt.ListSelector
        FileSelector              wt.FileSelector
        DropDownListManager       wt.DropDownListManager
        DatetimeSelector          wt.DatetimeSelector
        ColorSelector             wt.ColorSelector
        ButtonGrid                wt.ButtonGrid
        TopGrid                   matlab.ui.container.GridLayout
        Toolbar                   wt.Toolbar
        ToolbarLabel              matlab.ui.control.Label
    end


    %#ok<*INUSD,*MANU>

    properties (Access = public)
        ToolbarSection1
        ToolbarSection2
        ToolbarStateButton matlab.ui.control.StateButton
        ToolbarSection3h
        ToolbarSection3v
        ToolbarSlider
        ToolbarSection4
    end

    methods (Access = private)

        function createToolbarContent(app)

            % Create a horizontal section
            app.ToolbarSection1 = wt.toolbar.HorizontalSection();
            app.ToolbarSection1.Title = "NORMAL BUTTONS";
            app.ToolbarSection1.addButton("open_24.png", "Open");
            app.ToolbarSection1.addButton("save_24.png", "Save");

            % Create a horizontal section with state buttons
            app.ToolbarSection2 = wt.toolbar.HorizontalSection();
            app.ToolbarSection2.Title = "STATE BUTTONS";
            app.ToolbarStateButton(1) = app.ToolbarSection2.addStateButton("","Mode 1");
            app.ToolbarStateButton(2) = app.ToolbarSection2.addStateButton("","Mode 2");
            app.ToolbarStateButton(3) = app.ToolbarSection2.addStateButton("","Mode 3");

            % Set the state of the buttons
            app.ToolbarStateButton(1).Value = true;
            app.ToolbarStateButton(2).Value = false;
            app.ToolbarStateButton(3).Value = false;

            % Create a horizontal section with vertical subsection
            app.ToolbarSection3h = wt.toolbar.HorizontalSection();
            app.ToolbarSection3h.Title = "MIXED HORIZONTAL / VERTICAL";
            app.ToolbarSection3h.addButton("left_24.png","");
            app.ToolbarSection3h.addButton("right_24.png","");

            % Create the vertical subsection
            app.ToolbarSection3v = app.ToolbarSection3h.addVerticalSection();
            app.ToolbarSection3v.addButton("play_24.png","Play");
            app.ToolbarSection3v.addButton("stop_24.png","Stop");

            % Add a manual component to the  vertical section
            app.ToolbarSlider = uislider("Parent",[]);
            app.ToolbarSection3v.Component(end+1) = app.ToolbarSlider;

            % Attach a callback to the slider
            % This is required for manually adding components
            app.ToolbarSlider.ValueChangedFcn = @(src,evt)disp(evt);

            % Create more horizontal items
            app.ToolbarSection3h.addButton("pause_24.png","Pause");

            % Add another basic section
            app.ToolbarSection4 = wt.toolbar.HorizontalSection();
            app.ToolbarSection4.Title = "HELP";
            app.ToolbarSection4.addButton('help_24.png','Help');

            % Attach the horizontal sections to the toolbar
            app.Toolbar.Section = [
                app.ToolbarSection1
                app.ToolbarSection2
                app.ToolbarSection3h
                app.ToolbarSection4
                ];

        end

    end %methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Send "app" to base workspace for debugging
            assignin("base","app",app)

            % Populate the toolbar
            app.createToolbarContent();

            % Update button grid
            app.ButtonGrid.Text = ["Up","Down"];

            % Add list Items
            app.ListSelector.Items = ["Item 1", "Item 2", "Item 3", "Item 4"];
            app.ListSelector.Value = ["Item 2", "Item 4"];

            % Add 2-pane list Items
            app.ListSelectorTwoPane.Items = ["Item 1", "Item 2", "Item 3", "Item 4"];
            app.ListSelectorTwoPane.Value = ["Item 2", "Item 4"];

            % Start progress
            app.ProgressBar.startProgress();
            app.ProgressBar.setProgress(0.4, "Running...");

        end

        % Callback function: PasswordField
        function PasswordFieldValueChanged(app, event)

            value = app.PasswordField.Value;
            uialert(app.WidgetsToolboxExamplesAppUIFigure, value, "Password Entered")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WidgetsToolboxExamplesAppUIFigure = uifigure('Visible', 'off');
            app.WidgetsToolboxExamplesAppUIFigure.Name = 'Widgets Toolbox Examples App';
            app.MainGrid = uigridlayout(app.WidgetsToolboxExamplesAppUIFigure);
            app.TopGrid = uigridlayout(app.MainGrid);
            app.ToolbarLabel = uilabel(app.TopGrid);
            app.ToolbarLabel.Text = 'Toolbar';
            app.Toolbar = wt.Toolbar(app.TopGrid);
            app.LeftGrid = uigridlayout(app.MainGrid);
            app.ButtonGrid = wt.ButtonGrid(app.LeftGrid);
            app.ButtonGrid.Text = "";
            app.ButtonGrid.ButtonWidth = {'1x', '1x'};
            app.ButtonGrid.ButtonHeight = {'1x'};
            app.ColorSelector = wt.ColorSelector(app.LeftGrid);
            app.DatetimeSelector = wt.DatetimeSelector(app.LeftGrid);
            app.DatetimeSelector.Value = datetime([2025 3 4]);
            app.DropDownListManager = wt.DropDownListManager(app.LeftGrid);
            app.FileSelector = wt.FileSelector(app.LeftGrid);
            app.ListSelector = wt.ListSelector(app.LeftGrid);
            app.ButtonGridLabel = uilabel(app.LeftGrid);
            app.ButtonGridLabel.Text = 'Button Grid';
            app.CheckboxListLabel = uilabel(app.LeftGrid);
            app.CheckboxListLabel.Text = 'Checkbox List';
            app.ColorSelectorLabel = uilabel(app.LeftGrid);
            app.ColorSelectorLabel.Text = 'Color Selector';
            app.DatetimeSelectorLabel = uilabel(app.LeftGrid);
            app.DatetimeSelectorLabel.Text = 'Datetime Selector';
            app.DropDownListManagerLabel = uilabel(app.LeftGrid);
            app.DropDownListManagerLabel.Text = 'Drop Down List Manager';
            app.FileSelectorLabel = uilabel(app.LeftGrid);
            app.FileSelectorLabel.Text = 'File Selector';
            app.ListSelectorLabel = uilabel(app.LeftGrid);
            app.ListSelectorLabel.Text = 'List Selector';
            app.ListSelectorTwoPaneLabel = uilabel(app.LeftGrid);
            app.ListSelectorTwoPaneLabel.Text = 'List Selector Two Pane';
            app.ListSelectorTwoPane = wt.ListSelectorTwoPane(app.LeftGrid);
            app.ListSelectorTwoPane.HighlightedValueLeft = "Item 1";
            app.CheckboxList = wt.CheckboxList(app.LeftGrid);
            app.RightGrid = uigridlayout(app.MainGrid);
            app.NumericArray = wt.NumericArray(app.RightGrid);
            app.PasswordField = wt.PasswordField(app.RightGrid);
            app.PasswordField.ValueChangedFcn = createCallbackFcn(app, @PasswordFieldValueChanged, true);
            app.ProgressBar = wt.ProgressBar(app.RightGrid);
            app.ProgressBar.ShowCancel = 'on';
            app.RangeField = wt.RangeField(app.RightGrid);
            app.SliderCheckboxGroup = wt.SliderCheckboxGroup(app.RightGrid);
            app.SliderSpinner = wt.SliderSpinner(app.RightGrid);
            app.SliderSpinner.MinorTickLabels = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            app.NumericArrayLabel = uilabel(app.RightGrid);
            app.NumericArrayLabel.Text = 'Numeric Array';
            app.PasswordFieldLabel = uilabel(app.RightGrid);
            app.PasswordFieldLabel.Text = 'Password Field';
            app.ProgressBarLabel = uilabel(app.RightGrid);
            app.ProgressBarLabel.Text = 'Progress Bar';
            app.RangeFieldLabel = uilabel(app.RightGrid);
            app.RangeFieldLabel.Text = 'Range Field';
            app.SliderSpinnerComboLabel = uilabel(app.RightGrid);
            app.SliderSpinnerComboLabel.Text = 'Slider Spinner Combo';
            app.TaskStatusTableLabel = uilabel(app.RightGrid);
            app.TaskStatusTableLabel.Text = 'Task Status Table';
            app.SliderCheckboxLabel_2 = uilabel(app.RightGrid);
            app.SliderCheckboxLabel_2.Text = 'Slider Checkbox';
            app.TaskStatusTable = wt.TaskStatusTable(app.RightGrid);
            % Show the figure after all components are created
            app.WidgetsToolboxExamplesAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WidgetsExampleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WidgetsToolboxExamplesAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WidgetsToolboxExamplesAppUIFigure)
        end
    end
end